classdef LunarTestingChamberDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        TargetPressureDifferentialSlider  matlab.ui.control.Slider
        TargetPressureDifferentialSliderLabel  matlab.ui.control.Label
        CustomPressureDifferentialSwitch  matlab.ui.control.Switch
        CustomPressureDifferentialSwitchLabel  matlab.ui.control.Label
        WarningLamp                    matlab.ui.control.Lamp
        WarningLampLabel               matlab.ui.control.Label
        LowPressureLamp                matlab.ui.control.Lamp
        LowPressureLampLabel           matlab.ui.control.Label
        PressurizedLamp                matlab.ui.control.Lamp
        PressurizedLampLabel           matlab.ui.control.Label
        SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel  matlab.ui.control.Label
        ExportLogtoCSVButton           matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        ExternalTemperatureGauge       matlab.ui.control.Gauge
        ExternalTemperatureGaugeLabel  matlab.ui.control.Label
        InternalHumidityGauge          matlab.ui.control.Gauge
        InternalHumidityGaugeLabel     matlab.ui.control.Label
        InternalTemperatureGauge       matlab.ui.control.Gauge
        InternalTemperatureGaugeLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        FanRPMGauge                    matlab.ui.control.Gauge
        FanRPMGaugeLabel               matlab.ui.control.Label
        FanSpeedKnob                   matlab.ui.control.Knob
        FanSpeedKnobLabel              matlab.ui.control.Label
        ManualFanControlSwitch         matlab.ui.control.Switch
        ManualFanControlSwitchLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        esp             % Serial port object
        UpdateTimer     % Background timer for reading data
        StartTime
        tData = []
        pData = []
        tempData = []
        humData = []
        exportFlag = false % Description
        clockData = string.empty;
    end

    methods (Access = private)

        function readSerialData(app)
            % OUTER TRY: Catches if the ESP32 resets and severs the USB connection
            try
                if ~isempty(app.esp) && app.esp.NumBytesAvailable > 0
                    % INNER TRY: Catches normal garbled text during transmission
                    try
                        rawData = readline(app.esp);
                        values = str2double(split(rawData, ","));

                        % Check for 7 values
                        if length(values) == 7
                            d_psi = values(1);
                            out_t_f = values(2); % External Temp
                            in_t_f = values(3);  % Internal Temp
                            h_pct = values(4);   % Humidity
                            pwm_val = values(5);
                            rpm_val = values(6);
                            state = values(7);

                            % Update Arrays
                            app.clockData(end+1) = string(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS'));
                            app.tData(end+1) = toc(app.StartTime);
                            app.pData(end+1) = d_psi;
                            app.tempData(end+1) = in_t_f;
                            app.humData(end+1) = h_pct;

                            if length(app.tData) > 100
                                app.tData(1) = []; app.pData(1) = [];
                                app.clockData(1) = [];
                                app.tempData(1) = []; app.humData(1) = [];
                            end

                            % Update Pressure Plot
                            plot(app.UIAxes, app.tData, app.pData, '-b', 'LineWidth', 2);

                            % Update ALL Gauges with the new component names
                            app.ExternalTemperatureGauge.Value = out_t_f;
                            app.InternalTemperatureGauge.Value = in_t_f;
                            app.InternalHumidityGauge.Value = h_pct;
                            app.FanRPMGauge.Value = rpm_val;

                            if strcmp(app.ManualFanControlSwitch.Value, 'Off')
                                app.FanSpeedKnob.Value = pwm_val;
                            end

                            % Update Lamps & Alarms
                            if state == 1 || state == 4
                                app.PressurizedLamp.Color = 'green';
                                app.LowPressureLamp.Color = [0.8 0.8 0.8];
                            else
                                app.PressurizedLamp.Color = [0.8 0.8 0.8];
                                app.LowPressureLamp.Color = 'red';
                            end

                            % Warning Lamps & Messages
                            if state == 3 % SYSTEM HALTED
                                app.WarningLamp.Color = 'yellow';
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Text = 'SYSTEM HALTED: CRITICAL FAN TIMEOUT. MANUAL RESET REQUIRED.';
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.FontColor = [1 0 0];
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Visible = 'on';
                            elseif state == 2 || state == 4 % WARNING
                                app.WarningLamp.Color = 'yellow';
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Text = 'WARNING: FAN AT MAX FOR 5+ MINS. CHECK FOR LEAKS!';
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.FontColor = [1 0.8 0];
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Visible = 'on';
                            elseif state == 0 % LOW PRESSURE (NO WARNING)
                                app.WarningLamp.Color = [0.8 0.8 0.8];
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Text = 'STATUS: LOW PRESSURE - STABILIZING...';
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.FontColor = [1 0.5 0];
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Visible = 'on';
                            else % PRESSURIZED AND STABLE
                                app.WarningLamp.Color = [0.8 0.8 0.8];
                                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Visible = 'off';
                            end

                        end
                    catch
                        % Ignore bad serial strings
                    end
                end
            catch
                % THE HARDWARE RESET TRIGGER
                stop(app.UpdateTimer); % Stop the crashing background loop
                app.WarningLamp.Color = [0.8 0.8 0.8];
                app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Visible = 'off';

                app.UIFigure.Name = 'Lunar Testing Chamber Dashboard - Disconnected';

                % Wait for Windows to re-mount the USB! ---
                pause(2.5);

                % Now ask for the ports, after the ESP32 has fully rebooted
                app.COMPortDropDown.Enable = 'on';

                allPorts = serialportlist("all");
                if isempty(allPorts)
                    app.COMPortDropDown.Items = {'No Ports Found'};
                else
                    % Force the port list into a vertical column using (:)
                    portCellArray = cellstr(allPorts);
                    app.COMPortDropDown.Items = [{'Select Port...'}; portCellArray(:)];
                    app.COMPortDropDown.Value = 'Select Port...';
                end

                uialert(app.UIFigure, 'Connection lost due to hardware reset. Please re-select your COM port to resume.', 'Connection Severed');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default slider value before disabling it
            app.TargetPressureDifferentialSlider.Value = 15;
            % Disable controls on startup
            app.TargetPressureDifferentialSlider.Enable = 'off';
            app.FanSpeedKnob.Enable = 'off';

            app.UIFigure.Name = 'Lunar Testing Chamber Dashboard - Searching for Hardware...';
            drawnow;

            % --- NEW: Auto-Populate the Drop-Down Menu ---
            allPorts = serialportlist("all");
            if isempty(allPorts)
                app.COMPortDropDown.Items = {'No Ports Found'};
            else
                % Force the port list into a vertical column using (:)
                portCellArray = cellstr(allPorts);
                app.COMPortDropDown.Items = [{'Select Port...'}; portCellArray(:)];
                app.COMPortDropDown.Value = 'Select Port...';
            end

            ports = serialportlist("available");
            foundPort = false;

            % Give the ESP32 a second to boot up before we start hunting
            pause(1.5);

            for i = 1:length(ports)
                try
                    % Attempt to open the port
                    testPort = serialport(ports(i), 115200, "Timeout", 2);
                    configureTerminator(testPort, "LF");
                    flush(testPort);
                    pause(0.5);

                    if testPort.NumBytesAvailable > 0
                        rawData = readline(testPort);
                        parts = split(rawData, ",");

                        if length(parts) == 7
                            app.esp = testPort;
                            foundPort = true;
                            app.UIFigure.Name = sprintf('Lunar Testing Chamber Dashboard - Connected (%s)', ports(i));
                            app.COMPortDropDown.Value = ports(i);
                            break; % Stop hunting, we found it!
                        end
                    end
                    % If it opened but wasn't the ESP32, close it and keep hunting
                    clear testPort;
                catch
                    % IMPORTANT: If Windows throws a Bluetooth error, safely close the port
                    % and let the 'for' loop continue to the next port!
                    try clear testPort; catch; end
                end
            end

            if foundPort
                % Auto-Connect Succeeded! Start timer & lock manual controls
                app.COMPortDropDown.Enable = 'off';

                app.StartTime = tic;
                app.UpdateTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.2, ...
                    'TimerFcn', @(~,~) readSerialData(app));
                start(app.UpdateTimer);
            else
                % Auto-Connect Failed! Leave manual connect controls active
                app.UIFigure.Name = 'Lunar Testing Chamber Dashboard - Disconnected';
                uialert(app.UIFigure, 'Auto-connect failed. Please select your COM port from the drop-down menu to connect.', 'Manual Connect Required');
            end

        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)

            portName = app.COMPortDropDown.Value;

            % Safety check if no USBs are plugged in
            if strcmp(portName, 'No Ports Found')
                uialert(app.UIFigure, 'Please plug in the ESP32 first.', 'No Ports');
                return;
            elseif strcmp(portName, 'Select Port...')
                return; % Do nothing if they click the placeholder
            end

            app.UIFigure.Name = sprintf('Attempting to connect to %s...', portName);
            drawnow;

            try
                % Forcefully wipe any ghost connections first
                try delete(serialportfind); catch; end

                % Attempt to open the specific port
                app.esp = serialport(portName, 115200, "Timeout", 3);
                configureTerminator(app.esp, "LF");

                % Wait a moment for ESP32 to reboot and initialize sensors
                pause(2.5);
                flush(app.esp);

                % Update UI to show success
                app.UIFigure.Name = sprintf('Lunar Testing Chamber Dashboard - Connected (%s)', portName);
                app.COMPortDropDown.Enable = 'off';

                % Start the background timer to read data
                app.StartTime = tic;
                app.UpdateTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.2, ...
                    'TimerFcn', @(~,~) readSerialData(app));
                start(app.UpdateTimer);

            catch ME
                % 1. Make sure to close any failed connection attempts
                try delete(app.esp); catch; end
                
                % 2. UNLOCK THE MENU SO THEY CAN TRY AGAIN!
                app.COMPortDropDown.Enable = 'on'; 
                app.COMPortDropDown.Value = 'Select Port...';
                
                % 3. Show the exact Windows error
                app.UIFigure.Name = 'Lunar Testing Chamber Dashboard - Disconnected';
                uialert(app.UIFigure, ['Failed to connect to ', portName, '. Windows says: ', ME.message], 'Connection Error');
            end
        end

        % Value changed function: TargetPressureDifferentialSlider
        function TargetPressureDifferentialSliderValueChanged(app, event)
            if strcmp(app.CustomPressureDifferentialSwitch.Value, 'On')
                % Enable Slider, Disable Manual Controls
                app.TargetPressureDifferentialSlider.Enable = 'on';
                app.ManualFanControlSwitch.Value = 'Off';
                app.FanSpeedKnob.Enable = 'off';
                % Send Auto command and new target to ESP32
                if ~isempty(app.esp)
                    writeline(app.esp, "A");
                    writeline(app.esp, sprintf("T,%.0f", app.TargetPressureDifferentialSlider.Value));
                end
            else
                % Disable Slider, Revert to default 15 Pa
                app.TargetPressureDifferentialSlider.Enable = 'off';
                app.TargetPressureDifferentialSlider.Value = 15; % Visually snap back
                if ~isempty(app.esp)
                    writeline(app.esp, "T,15");
                end
            end
        end

        % Value changed function: CustomPressureDifferentialSwitch
        function CustomPressureDifferentialSwitchValueChanged(app, event)
            if strcmp(app.CustomPressureDifferentialSwitch.Value, 'On')
                % Enable Slider, Disable Manual Controls
                app.TargetPressureDifferentialSlider.Enable = 'on';
                app.ManualFanControlSwitch.Value = 'Off';
                app.FanSpeedKnob.Enable = 'off';
                % Send Auto command and new target to ESP32
                if ~isempty(app.esp)
                    writeline(app.esp, "A");
                    writeline(app.esp, sprintf("T,%.0f", app.TargetPressureDifferentialSlider.Value));
                end
            else
                % Disable Slider, Revert to default 15 Pa
                app.TargetPressureDifferentialSlider.Enable = 'off';
                app.TargetPressureDifferentialSlider.Value = 15; % Visually snap back
                if ~isempty(app.esp)
                    writeline(app.esp, "T,15");
                end
            end
        end

        % Value changed function: ManualFanControlSwitch
        function ManualFanControlSwitchValueChanged(app, event)
            if strcmp(app.ManualFanControlSwitch.Value, 'On')
                % Enable Knob, Disable Auto Controls
                app.FanSpeedKnob.Enable = 'on';
                app.CustomPressureDifferentialSwitch.Value = 'Off';
                app.TargetPressureDifferentialSlider.Enable = 'off';

                % Send Manual PWM command to ESP32
                if ~isempty(app.esp)
                    writeline(app.esp, sprintf("M,%d", round(app.FanSpeedKnob.Value)));
                end
            else
                % Disable Knob, Return to Auto Mode
                app.FanSpeedKnob.Enable = 'off';
                if ~isempty(app.esp)
                    writeline(app.esp, "A");
                    % Since Custom switch is off, enforce default pressure
                    writeline(app.esp, "T,0.0022");
                end
            end
        end

        % Value changed function: FanSpeedKnob
        function FanSpeedKnobValueChanged(app, event)
            if strcmp(app.ManualFanControlSwitch.Value, 'On') && ~isempty(app.esp)
                writeline(app.esp, sprintf("M,%d", round(app.FanSpeedKnob.Value)));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 1. Forcefully try to stop the timer and ignore errors if it fails
            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
                % If it's a 'double' or doesn't exist, MATLAB just skips this
            end

            % 2. Safely close the serial port
            try
                delete(app.esp);
            catch
                % If no port was open, just move on
            end

            % 3. Destroy the app window
            delete(app.UIFigure);
        end

        % Button pushed function: ExportLogtoCSVButton
        function ExportLogtoCSVButtonPushed(app, event)

            % 1. Check if there is actually data to save
            if isempty(app.tData)
                uialert(app.UIFigure, 'No data has been recorded yet!', 'Export Error');
                return;
            end

            % 2. Open a standard Windows "Save As" dialog box
            [file, path] = uiputfile('*.csv', 'Save Log As', 'LunarChamberLog.csv');

            % 3. Check if the user clicked "Cancel"
            if isequal(file, 0) || isequal(path, 0)
                return; % Do nothing and go back to the app
            end

            % 4. Combine the chosen folder and filename
            finalPath = fullfile(path, file);

            % 5. Build the table and save it to the exact chosen location
            try
                T = table(app.clockData', app.tData', app.pData', app.tempData', app.humData', ...
                    'VariableNames', {'Timestamp', 'Elapsed_Sec', 'Delta_Pa', 'Temp_F', 'Humidity_pct'});

                writetable(T, finalPath);

                % Success popup
                uialert(app.UIFigure, sprintf('Data successfully saved to:\n%s', finalPath), 'Export Complete');
            catch ME
                % Catch any errors (like if the file is currently open in Excel)
                uialert(app.UIFigure, ['Failed to save file: ', ME.message], 'Export Error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExportLogtoCSVButton = uibutton(app.LeftPanel, 'push');
            app.ExportLogtoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogtoCSVButtonPushed, true);
            app.ExportLogtoCSVButton.Text = 'Export Log to CSV';
            app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel = uilabel(app.LeftPanel);
            app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Visible = 'off';
            app.SYSTEMHALTEDFANTIMEOUTMANUALRESETREQUIREDLabel.Text = 'SYSTEM HALTED: FAN TIMEOUT. MANUAL RESET REQUIRED';
            app.PressurizedLampLabel = uilabel(app.LeftPanel);
            app.PressurizedLampLabel.Text = 'Pressurized';
            app.PressurizedLamp = uilamp(app.LeftPanel);
            app.LowPressureLampLabel = uilabel(app.LeftPanel);
            app.LowPressureLampLabel.Text = 'Low Pressure';
            app.LowPressureLamp = uilamp(app.LeftPanel);
            app.WarningLampLabel = uilabel(app.LeftPanel);
            app.WarningLampLabel.Text = 'Warning';
            app.WarningLamp = uilamp(app.LeftPanel);
            app.CustomPressureDifferentialSwitchLabel = uilabel(app.LeftPanel);
            app.CustomPressureDifferentialSwitchLabel.Text = 'Custom Pressure Differential';
            app.CustomPressureDifferentialSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CustomPressureDifferentialSwitch.ValueChangedFcn = createCallbackFcn(app, @CustomPressureDifferentialSwitchValueChanged, true);
            app.TargetPressureDifferentialSliderLabel = uilabel(app.LeftPanel);
            app.TargetPressureDifferentialSliderLabel.Text = 'Target Pressure Differential';
            app.TargetPressureDifferentialSlider = uislider(app.LeftPanel);
            app.TargetPressureDifferentialSlider.Limits = [0 30];
            app.TargetPressureDifferentialSlider.MajorTicks = [0 10 20 30];
            app.TargetPressureDifferentialSlider.MajorTickLabels = {'0', '10', '20', '30'};
            app.TargetPressureDifferentialSlider.ValueChangedFcn = createCallbackFcn(app, @TargetPressureDifferentialSliderValueChanged, true);
            app.COMPortDropDownLabel = uilabel(app.LeftPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.LeftPanel);
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Pressure Differential')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Pressure Difference [Pa]')
            zlabel(app.UIAxes, 'Z')
            app.InternalTemperatureGaugeLabel = uilabel(app.CenterPanel);
            app.InternalTemperatureGaugeLabel.Text = 'Internal Temperature';
            app.InternalTemperatureGauge = uigauge(app.CenterPanel, 'circular');
            app.InternalTemperatureGauge.Limits = [0 140];
            app.InternalHumidityGaugeLabel = uilabel(app.CenterPanel);
            app.InternalHumidityGaugeLabel.Text = 'Internal Humidity';
            app.InternalHumidityGauge = uigauge(app.CenterPanel, 'circular');
            app.ExternalTemperatureGaugeLabel = uilabel(app.CenterPanel);
            app.ExternalTemperatureGaugeLabel.Text = 'External Temperature';
            app.ExternalTemperatureGauge = uigauge(app.CenterPanel, 'circular');
            app.ExternalTemperatureGauge.Limits = [0 140];
            app.RightPanel = uipanel(app.GridLayout);
            app.ManualFanControlSwitchLabel = uilabel(app.RightPanel);
            app.ManualFanControlSwitchLabel.Text = 'Manual Fan Control';
            app.ManualFanControlSwitch = uiswitch(app.RightPanel, 'slider');
            app.ManualFanControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualFanControlSwitchValueChanged, true);
            app.FanSpeedKnobLabel = uilabel(app.RightPanel);
            app.FanSpeedKnobLabel.Text = 'Fan Speed';
            app.FanSpeedKnob = uiknob(app.RightPanel, 'continuous');
            app.FanSpeedKnob.Limits = [0 255];
            app.FanSpeedKnob.ValueChangedFcn = createCallbackFcn(app, @FanSpeedKnobValueChanged, true);
            app.FanRPMGaugeLabel = uilabel(app.RightPanel);
            app.FanRPMGaugeLabel.Text = 'Fan RPM';
            app.FanRPMGauge = uigauge(app.RightPanel, 'circular');
            app.FanRPMGauge.Limits = [0 5500];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LunarTestingChamberDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end